classdef assignment1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UserstoPlotEditField          matlab.ui.control.EditField
        UserstoPlotEditFieldLabel     matlab.ui.control.Label
        ReferenceSNRdBEditField       matlab.ui.control.EditField
        ReferenceSNRdBEditFieldLabel  matlab.ui.control.Label
        AmplitudevectordBEditField    matlab.ui.control.EditField
        AmplitudevectordBEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        NoiseSeedEditField            matlab.ui.control.NumericEditField
        NoiseSeedEditFieldLabel       matlab.ui.control.Label
        ExitButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        NSimulatedSymbolsEditField    matlab.ui.control.NumericEditField
        NSimulatedSymbolsEditFieldLabel  matlab.ui.control.Label
        NSequencebitsEditField        matlab.ui.control.NumericEditField
        NLabel                        matlab.ui.control.Label
        SequenceSeedEditField         matlab.ui.control.NumericEditField
        SequenceSeedEditFieldLabel    matlab.ui.control.Label
        Label4                        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SUMF     
        DECO    
        MMSE   
        OPT    
        Near_Far
        ref_snr_dB 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         
            Seq_seed = app.SequenceSeedEditField.Value; 
            Noise_seed = app.NoiseSeedEditField.Value;
            Users_to_plot_string = app.UserstoPlotEditField.Value; % String
            seq_len = app.NSequencebitsEditField.Value;
            A_k_db_string = app.AmplitudevectordBEditField.Value; % String
            symbol_num = app.NSimulatedSymbolsEditField.Value;
            ref_snr_dB_string = app.ReferenceSNRdBEditField.Value; % String
            
            % string to vector transformation
            temp1 = split(A_k_db_string,":");
            A_k_db = str2double(temp1(1)):str2double(temp1(end));
            temp2 = split(ref_snr_dB_string,":");
            app.ref_snr_dB = str2double(temp2(1)):str2double(temp2(end));
            temp3 = split(Users_to_plot_string,",");
            user_idx = transpose(str2double(temp3));
            

            % Call the RX_BER_Calculator function
            [app.SUMF, app.DECO, app.MMSE, app.OPT, app.Near_Far] = RX_BER_Calculator(Seq_seed, Noise_seed, seq_len, A_k_db, symbol_num, app.ref_snr_dB);
        
            % Check if the user index is valid
            if isempty(user_idx) || any(user_idx < 1) || any(user_idx > size(app.SUMF, 1))
                % Show a warning if the user index is invalid
                uialert(app.UIFigure, 'Please enter a valid user index or a vector of valid indices.', 'Invalid Input');
                return;
            end
            
            plot_BER_AppDesigner(app, app.SUMF, app.DECO, app.MMSE, app.OPT, app.ref_snr_dB, user_idx);
            
            num_users = length(app.Near_Far);  % Number of users
            user_indices = (1:num_users)';     % Create a column vector of user indices

            % Create a cell array for the table data (2 columns: user index and Near Far Resistance)
            table_data = cell(num_users, 2);   
            for i = 1:num_users
                table_data{i, 1} = user_indices(i);        % First column: user index
                table_data{i, 2} = app.Near_Far(i);        % Second column: Near Far Resistance value
            end
        
            % Set the UITable Data property to the table_data
            app.UITable.Data = table_data;
        
            % Set UITable column names
            app.UITable.ColumnName = {'User', 'Near Far Resistance'};
        end

        % Callback function
        function UserstoPlotEditFieldValueChanged(app, event)
                % Get the user index input (it could be a string like '[1,3]')
                user_input = app.UserstoPlotEditField.Value;
            
                % Convert the string input to a numeric array (e.g., '[1,3]' -> [1,3])
                temp3 = split(user_input,",");
                user_idx = transpose(str2double(temp3));
                
                % Check if the start buttom is clicked and there is a
                % simulation to plot
                try
                    app.SUMF;
                catch
                    uialert(app.UIFigure, 'Please click "start" bottom at least once to run the simulation .', 'Start First');
                    return;
                end
                % Check if the user index is valid
                if isempty(user_idx) || any(user_idx < 1) || any(user_idx > size(app.SUMF, 1))
                    % Show a warning if the user index is invalid
                    uialert(app.UIFigure, 'Please enter a valid user index or a vector of valid indices.', 'Invalid Input');
                    return;
                end
            
                % Call the plot function with the selected user indices
                plot_BER_AppDesigner(app, app.SUMF, app.DECO, app.MMSE, app.OPT, app.ref_snr_dB, user_idx);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);  % This will close the app window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BER vs SNR for Receiver ')
            xlabel(app.UIAxes, 'Reference SNR (dB)')
            ylabel(app.UIAxes, 'BER')
            zlabel(app.UIAxes, 'Z')
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.SequenceSeedEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceSeedEditFieldLabel.Text = ' Sequence Seed';
            app.SequenceSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SequenceSeedEditField.Value = 1;
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N. Sequence bits';
            app.NSequencebitsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NSequencebitsEditField.Value = 32;
            app.NSimulatedSymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.NSimulatedSymbolsEditFieldLabel.Text = 'N. Simulated Symbols';
            app.NSimulatedSymbolsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NSimulatedSymbolsEditField.Value = 1000000;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.NoiseSeedEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseSeedEditFieldLabel.Text = ' Noise Seed';
            app.NoiseSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseSeedEditField.Value = 2;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'User'; 'Near-far resistance'};
            app.UITable.RowName = {};
            app.AmplitudevectordBEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudevectordBEditFieldLabel.Text = 'Amplitude vector (dB)';
            app.AmplitudevectordBEditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudevectordBEditField.Value = '0:3';
            app.ReferenceSNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceSNRdBEditFieldLabel.Text = 'Reference SNR (dB)';
            app.ReferenceSNRdBEditField = uieditfield(app.UIFigure, 'text');
            app.ReferenceSNRdBEditField.Value = '0:10';
            app.UserstoPlotEditFieldLabel = uilabel(app.UIFigure);
            app.UserstoPlotEditFieldLabel.Text = 'Users to Plot';
            app.UserstoPlotEditField = uieditfield(app.UIFigure, 'text');
            app.UserstoPlotEditField.Value = '1,3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end