classdef assignment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SeedNofSamplesEditField        matlab.ui.control.EditField
        SeedNofSamplesEditFieldLabel   matlab.ui.control.Label
        ResultLabel                    matlab.ui.control.Label
        ProgressGauge                  matlab.ui.control.LinearGauge
        ProgressGaugeLabel             matlab.ui.control.Label
        OutageProbabilitySpinner       matlab.ui.control.Spinner
        OutageProbabilitySpinnerLabel  matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        SNRdBEditField                 matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel            matlab.ui.control.Label
        NofRxantennasEditField         matlab.ui.control.NumericEditField
        NofRxantennasEditFieldLabel    matlab.ui.control.Label
        NofTxantennasEditField         matlab.ui.control.NumericEditField
        NofTxantennasEditFieldLabel    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            % Inputs 
            n_t = app.NofTxantennasEditField.Value;
            n_r = app.NofRxantennasEditField.Value;
            SNR_dB = app.SNRdBEditField.Value;
            perc_cap = app.OutageProbabilitySpinner.Value;

            seedAndSamplesStr = app.SeedNofSamplesEditField.Value; % string
            seedAndSamples = split(seedAndSamplesStr, ",");
            seed = str2double(seedAndSamples(1));
            N_samples = str2double(seedAndSamples(2));

            app.ProgressGauge.Value = 10;
            drawnow;

            tic;

            % main function 
            [Capacity_Wo, Capacity_W, Outage_W_CSIT, Outage_Wo_CSIT, Ergodic_W_CSIT, Ergodic_Wo_CSIT] = mainFunctions(n_t, n_r, SNR_dB, seed, N_samples, perc_cap);

            app.ProgressGauge.Value = 70;
            drawnow;

            
            elapsedTime = toc;

            app.ResultLabel.Text = sprintf('Outage capacity = %.3f w/o CSIT and %.3f with CSIT\nEgodic capacity = %.3f w/o CSIT and %.3f with CSIT\nRunning time: %.4f sec', Outage_Wo_CSIT, Outage_W_CSIT, Ergodic_Wo_CSIT, Ergodic_W_CSIT, elapsedTime);


            % Plot 
            plot(app.UIAxes, Capacity_W, (1:N_samples)/N_samples, 'r', 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, Capacity_Wo, (1:N_samples)/N_samples, '--b', 'LineWidth', 2);
            legend(app.UIAxes, 'Capacity with CSIT', 'Capacity w/o CSIT', 'Location', 'NorthWest');
            xlabel(app.UIAxes, 'R [bit/s/Hz]');
            ylabel(app.UIAxes, 'P(C < R)');
            hold(app.UIAxes, 'off');

            % Show progress
            app.ProgressGauge.Value = 100;
            drawnow;


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'R [bit/sHz]')
            ylabel(app.UIAxes, 'P(C<R)')
            zlabel(app.UIAxes, 'Z')
            app.NofTxantennasEditFieldLabel = uilabel(app.UIFigure);
            app.NofTxantennasEditFieldLabel.Text = 'N. of Tx antennas';
            app.NofTxantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NofRxantennasEditFieldLabel = uilabel(app.UIFigure);
            app.NofRxantennasEditFieldLabel.Text = 'N. of Rx antennas';
            app.NofRxantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.OutageProbabilitySpinnerLabel = uilabel(app.UIFigure);
            app.OutageProbabilitySpinnerLabel.Text = 'Outage Probability';
            app.OutageProbabilitySpinner = uispinner(app.UIFigure);
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress ( %)';
            app.ProgressGauge = uigauge(app.UIFigure, 'linear');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.SeedNofSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.SeedNofSamplesEditFieldLabel.Text = 'Seed, N. of Samples ';
            app.SeedNofSamplesEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end