classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TolerancesEditField           matlab.ui.control.EditField
        TolerancesEditFieldLabel      matlab.ui.control.Label
        UseranglesdegEditField        matlab.ui.control.EditField
        UseranglesdegEditFieldLabel   matlab.ui.control.Label
        ClickStarttorunLabel          matlab.ui.control.Label
        ElapsedTimeLabel              matlab.ui.control.Label
        IterationsLabel               matlab.ui.control.Label
        TotalPowerLabel               matlab.ui.control.Label
        SINRsLabel                    matlab.ui.control.Label
        PrecodingmatrixPLabel         matlab.ui.control.Label
        SINRsUITable                  matlab.ui.control.Table
        PrecodingMatrixUITable        matlab.ui.control.Table
        ExitButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        TargetuserSINRsdBEditField    matlab.ui.control.NumericEditField
        TargetuserSINRsdBEditFieldLabel  matlab.ui.control.Label
        DistancesmEditField           matlab.ui.control.NumericEditField
        DistancesmEditFieldLabel      matlab.ui.control.Label
        NoisePowerEditField           matlab.ui.control.NumericEditField
        NoisePowerEditFieldLabel      matlab.ui.control.Label
        AntennaSpacingEditField       matlab.ui.control.NumericEditField
        AntennaSpacingEditFieldLabel  matlab.ui.control.Label
        NantennasEditField            matlab.ui.control.NumericEditField
        NantennasEditFieldLabel       matlab.ui.control.Label
        lambdamEditField              matlab.ui.control.NumericEditField
        lambdaLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        polarAx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.polarAx = polaraxes(app.UIFigure);
            uistack(app.polarAx, 'top');
            app.polarAx.Units = 'pixels';
            app.polarAx.Position = [276,20,340,340];
            thetalim(app.polarAx,[0,180])
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ClickStarttorunLabel.Text = "Running";
            lambda = app.lambdamEditField.Value;
            n_t = app.NantennasEditField.Value;
            H_spacing = app.AntennaSpacingEditField.Value; % Antenna spacing as a multiple of lambda
            alpha = app.UseranglesdegEditField.Value;  % Convert user angles from string to numeric array
            P_noise = app.NoisePowerEditField.Value; % Convert noise power from string to numeric value
            d = app.DistancesmEditField.Value; % Convert distance from string to numeric value
            target_SINR_dB = app.TargetuserSINRsdBEditField.Value; % Convert target SINR to numeric
            tolerances = app.TolerancesEditField.Value;
            
            temp = split(alpha,",");
            alpha = transpose(str2double(temp));
            temp = split(tolerances,",");
            tolerances = transpose(str2double(temp));

            % Call to external function to perform calculations
            [Precoding_Matrix, SINR_dB, P_total_dB, iterations_fixed, iterations_newton, theta, radiation_pattern, elapsed_time] = calculate_precoding(lambda, n_t, H_spacing, alpha, P_noise, d, target_SINR_dB, tolerances);

            % Update GUI with calculated results
            app.PrecodingMatrixUITable.Data = Precoding_Matrix; % Display precoding matrix
            app.SINRsUITable.Data = transpose(SINR_dB); % Display SINRs as vector
            app.TotalPowerLabel.Text = ['Tx power: ', num2str(P_total_dB), ' dB']; % Display total transmitted power in dB
            app.IterationsLabel.Text = ['Iterations (fixed-point + Newton): ', num2str(iterations_fixed), ' + ', num2str(iterations_newton)]; % Display iterations
            app.ElapsedTimeLabel.Text = ['Elapsed time: ', num2str(elapsed_time), ' s']; % Display elapsed t
           
            
            %display
            disp(lambda);
            disp(H_spacing);
            disp(d);


            % Use polar coordinates by converting them to Cartesian coordinates
            %app.RadiationPatternAxes.Visible = 'off';
            polarplot(app.polarAx,theta, radiation_pattern)
            thetalim(app.polarAx,[0,180])
            rlim(app.polarAx,[0,1])
            app.ClickStarttorunLabel.Text = "Finished";


%             % Plot the radiation pattern (Hamze)
%             axes(app.PanelAxes); % Assuming PanelAxes is the handle to the axes in your panel
%             cla(app.PanelAxes);
%             hold(app.PanelAxes, 'on');
%             polarplot(app.PanelAxes, theta, radiation_pattern(k, :));
%             hold(app.PanelAxes, 'off');
%             legend(app.PanelAxes, arrayfun(@(x) ['User ', num2str(x)], 1:length(alpha), 'UniformOutput', false));
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lambdaLabel = uilabel(app.UIFigure);
            app.lambdaLabel.Interpreter = 'latex';
            app.lambdaLabel.Text = {'$\lambda$ (m)'; ''};
            app.lambdamEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambdamEditField.Value = 0.01;
            app.NantennasEditFieldLabel = uilabel(app.UIFigure);
            app.NantennasEditFieldLabel.Text = 'N. antennas';
            app.NantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NantennasEditField.Value = 16;
            app.AntennaSpacingEditFieldLabel = uilabel(app.UIFigure);
            app.AntennaSpacingEditFieldLabel.Text = 'Antenna Spacing';
            app.AntennaSpacingEditField = uieditfield(app.UIFigure, 'numeric');
            app.AntennaSpacingEditField.Value = 0.5;
            app.NoisePowerEditFieldLabel = uilabel(app.UIFigure);
            app.NoisePowerEditFieldLabel.Text = 'Noise Power';
            app.NoisePowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisePowerEditField.Value = 1e-07;
            app.DistancesmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancesmEditFieldLabel.Text = 'Distances (m)';
            app.DistancesmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancesmEditField.Value = 10;
            app.TargetuserSINRsdBEditFieldLabel = uilabel(app.UIFigure);
            app.TargetuserSINRsdBEditFieldLabel.Text = 'Target user SINRs (dB)';
            app.TargetuserSINRsdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetuserSINRsdBEditField.Value = 15;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PrecodingMatrixUITable = uitable(app.UIFigure);
            app.PrecodingMatrixUITable.ColumnName = {'Column 1'; 'Column 2'};
            app.PrecodingMatrixUITable.RowName = {};
            app.SINRsUITable = uitable(app.UIFigure);
            app.SINRsUITable.ColumnName = {'Column 1'};
            app.SINRsUITable.RowName = {};
            app.PrecodingmatrixPLabel = uilabel(app.UIFigure);
            app.PrecodingmatrixPLabel.Text = 'Precoding matrix P';
            app.SINRsLabel = uilabel(app.UIFigure);
            app.SINRsLabel.Text = 'SINRs';
            app.TotalPowerLabel = uilabel(app.UIFigure);
            app.TotalPowerLabel.Text = 'TotalPower';
            app.IterationsLabel = uilabel(app.UIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.ElapsedTimeLabel = uilabel(app.UIFigure);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.ClickStarttorunLabel = uilabel(app.UIFigure);
            app.ClickStarttorunLabel.Text = 'Click Start to run';
            app.UseranglesdegEditFieldLabel = uilabel(app.UIFigure);
            app.UseranglesdegEditFieldLabel.Text = 'User angles (deg)';
            app.UseranglesdegEditField = uieditfield(app.UIFigure, 'text');
            app.UseranglesdegEditField.Value = '30,40,90,130';
            app.TolerancesEditFieldLabel = uilabel(app.UIFigure);
            app.TolerancesEditFieldLabel.Text = 'Tolerances';
            app.TolerancesEditField = uieditfield(app.UIFigure, 'text');
            app.TolerancesEditField.Value = '1e-3,1e-12';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end